classdef ArrayDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadADfileMenu                matlab.ui.container.Menu
        SaveADfileMenu                matlab.ui.container.Menu
        ExportresultstotxtMenu        matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        ROIbuilderMenu                matlab.ui.container.Menu
        INPUTVARIABLESPanel           matlab.ui.container.Panel
        SelectHeadModelButton         matlab.ui.control.Button
        HeadModelPathText             matlab.ui.control.EditField
        SelectROIButton               matlab.ui.control.Button
        ROIPathText                   matlab.ui.control.EditField
        SourcesEditFieldLabel         matlab.ui.control.Label
        SourcesField                  matlab.ui.control.NumericEditField
        DetectorsEditFieldLabel       matlab.ui.control.Label
        DetectorsField                matlab.ui.control.NumericEditField
        MinSDSmmSliderLabel           matlab.ui.control.Label
        MinSDSSlider                  matlab.ui.control.Slider
        MaxgoodSDSmmLabel             matlab.ui.control.Label
        MaxGoodSDSSlider              matlab.ui.control.Slider
        MaxSDSmmSliderLabel           matlab.ui.control.Label
        MaxSDSSlider                  matlab.ui.control.Slider
        CoverageweightEditFieldLabel  matlab.ui.control.Label
        CoverageWeightField           matlab.ui.control.NumericEditField
        MinSDSField                   matlab.ui.control.NumericEditField
        MaxGoodSDSField               matlab.ui.control.NumericEditField
        MaxSDSField                   matlab.ui.control.NumericEditField
        SaveADfileButton              matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        ExportResultstotxtButton      matlab.ui.control.Button
        ROIAxes                       matlab.ui.control.UIAxes
        ArrayDesignerOutputInfoTextAreaLabel  matlab.ui.control.Label
        OutputInfoText                matlab.ui.control.TextArea
        ArrayAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AD;
%         AD.inputs.nS = [];
%         AD.inputs.nD = [];
%         AD.inputs.minRhoOpt = [];
%         AD.inputs.maxGoodRho = [];
%         AD.inputs.maxRho = [];
%         AD.inputs.coverageWeight = [];
%         AD.inputs.pathnameHeadModel = [];
%         AD.inputs.pathnameROI = [];
%         AD.inputs.pathnameOutput = [];
%         AD.inputs.pathnameWeights = [];
%         AD.inputs.coverageThresh = [];
    end
    
    methods (Access = private)
        
        function results = updateAll(app)
            
            %Update all the inputs components
            updateVariables(app);
            
            %Update figures
            updateFigures(app);
        end
        
        function results = updateVariables(app)
            %Update all the inputs components
            [~,tmp,ext] = fileparts(app.AD.inputs.pathnameHeadModel);
            app.HeadModelPathText.Value = [tmp];
            
            [~,tmp,ext] = fileparts(app.AD.inputs.pathnameROI);
            app.ROIPathText.Value = [tmp];
            
            app.SourcesField.Value = app.AD.inputs.nS;
            
            app.DetectorsField.Value = app.AD.inputs.nD;
            
            app.CoverageWeightField.Value = app.AD.inputs.coverageWeight;
            
            app.MinSDSField.Value = app.AD.inputs.minRhoOpt;
            app.MinSDSSlider.Value = app.AD.inputs.minRhoOpt;
            
            app.MaxGoodSDSField.Value = app.AD.inputs.maxGoodRho;
            app.MaxGoodSDSSlider.Value = app.AD.inputs.maxGoodRho;
            
            app.MaxSDSField.Value = app.AD.inputs.maxRho;
            app.MaxSDSSlider.Value = app.AD.inputs.maxRho;
        end
        
        function results = updateFigures(app)
            
            %Update ROI plot
            if ~isempty(app.AD.inputs.pathnameROI)
                plotROI(app.AD.inputs.pathnameHeadModel,app.AD.inputs.pathnameROI,'hAxes',app.ROIAxes);
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadADfileMenu
        function LoadADfileMenuSelected(app, event)
            [ADfilename, ADpathname] = uigetfile('*.AD', 'Select .AD file');
            app.AD = load(fullfile(ADpathname,ADfilename),"-mat");
            
            updateAll(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadADfileMenu = uimenu(app.FileMenu);
            app.LoadADfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadADfileMenuSelected, true);
            app.LoadADfileMenu.Text = 'Load AD file';
            app.SaveADfileMenu = uimenu(app.FileMenu);
            app.SaveADfileMenu.Text = 'Save AD file';
            app.ExportresultstotxtMenu = uimenu(app.FileMenu);
            app.ExportresultstotxtMenu.Text = 'Export results to .txt';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.ROIbuilderMenu = uimenu(app.ToolsMenu);
            app.ROIbuilderMenu.Text = 'ROI builder';
            app.INPUTVARIABLESPanel = uipanel(app.UIFigure);
            app.INPUTVARIABLESPanel.Title = 'INPUT VARIABLES';
            app.SelectHeadModelButton = uibutton(app.INPUTVARIABLESPanel, 'push');
            app.SelectHeadModelButton.Text = 'Select Head Model';
            app.HeadModelPathText = uieditfield(app.INPUTVARIABLESPanel, 'text');
            app.HeadModelPathText.Editable = 'off';
            app.SelectROIButton = uibutton(app.INPUTVARIABLESPanel, 'push');
            app.SelectROIButton.Text = 'Select ROI';
            app.ROIPathText = uieditfield(app.INPUTVARIABLESPanel, 'text');
            app.ROIPathText.Editable = 'off';
            app.SourcesEditFieldLabel = uilabel(app.INPUTVARIABLESPanel);
            app.SourcesEditFieldLabel.Text = '# Sources';
            app.SourcesField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.SourcesField.Limits = [1 100];
            app.SourcesField.Value = 1;
            app.DetectorsEditFieldLabel = uilabel(app.INPUTVARIABLESPanel);
            app.DetectorsEditFieldLabel.Text = '# Detectors';
            app.DetectorsField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.DetectorsField.Limits = [1 100];
            app.DetectorsField.Value = 1;
            app.MinSDSmmSliderLabel = uilabel(app.INPUTVARIABLESPanel);
            app.MinSDSmmSliderLabel.Text = 'Min. SDS (mm)';
            app.MinSDSSlider = uislider(app.INPUTVARIABLESPanel);
            app.MinSDSSlider.Limits = [1 30];
            app.MinSDSSlider.MajorTicks = [1 10 20 30 40 50];
            app.MinSDSSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.MinSDSSlider.Value = 1;
            app.MaxgoodSDSmmLabel = uilabel(app.INPUTVARIABLESPanel);
            app.MaxgoodSDSmmLabel.Text = 'Max. good SDS (mm)';
            app.MaxGoodSDSSlider = uislider(app.INPUTVARIABLESPanel);
            app.MaxGoodSDSSlider.Limits = [20 50];
            app.MaxGoodSDSSlider.MajorTicks = [1 10 20 30 40 50];
            app.MaxGoodSDSSlider.MinorTicks = [20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            app.MaxGoodSDSSlider.Value = 20;
            app.MaxSDSmmSliderLabel = uilabel(app.INPUTVARIABLESPanel);
            app.MaxSDSmmSliderLabel.Text = 'Max. SDS (mm)';
            app.MaxSDSSlider = uislider(app.INPUTVARIABLESPanel);
            app.MaxSDSSlider.Limits = [20 60];
            app.MaxSDSSlider.MajorTicks = [1 10 20 30 40 50 60];
            app.MaxSDSSlider.MinorTicks = [20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            app.MaxSDSSlider.Value = 20;
            app.CoverageweightEditFieldLabel = uilabel(app.INPUTVARIABLESPanel);
            app.CoverageweightEditFieldLabel.Text = {'Coverage '; 'weight'};
            app.CoverageWeightField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.CoverageWeightField.Limits = [0 100];
            app.MinSDSField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.MaxGoodSDSField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.MaxSDSField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.SaveADfileButton = uibutton(app.INPUTVARIABLESPanel, 'push');
            app.SaveADfileButton.Text = 'Save AD file';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'RUN ARRAY DESIGNER';
            app.ExportResultstotxtButton = uibutton(app.UIFigure, 'push');
            app.ExportResultstotxtButton.Text = 'Export Results to .txt';
            app.ROIAxes = uiaxes(app.UIFigure);
            title(app.ROIAxes, '')
            xlabel(app.ROIAxes, '')
            ylabel(app.ROIAxes, '')
            app.ROIAxes.XTick = [];
            app.ROIAxes.YTick = [];
            app.ArrayDesignerOutputInfoTextAreaLabel = uilabel(app.UIFigure);
            app.ArrayDesignerOutputInfoTextAreaLabel.Text = 'Array Designer Output Info:';
            app.OutputInfoText = uitextarea(app.UIFigure);
            app.OutputInfoText.Editable = 'off';
            app.ArrayAxes = uiaxes(app.UIFigure);
            title(app.ArrayAxes, '')
            xlabel(app.ArrayAxes, '')
            ylabel(app.ArrayAxes, '')
            app.ArrayAxes.XTick = [];
            app.ArrayAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArrayDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end