classdef ROIBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ROIAxes                      matlab.ui.control.UIAxes
        Panel                        matlab.ui.container.Panel
        SelectHeadModelButton        matlab.ui.control.Button
        HeadModelPathText            matlab.ui.control.EditField
        SelectParcellationDropDownLabel  matlab.ui.control.Label
        SelectParcellationDropDown   matlab.ui.control.DropDown
        SelectExistingROIButton      matlab.ui.control.Button
        CurrentROIText               matlab.ui.control.EditField
        ROIBuilderLabel              matlab.ui.control.Label
        ParcelselectorPanel          matlab.ui.container.Panel
        ParcelsallListBoxLabel       matlab.ui.control.Label
        ParcelsallListBox            matlab.ui.control.ListBox
        ParcelsincludedListBoxLabel  matlab.ui.control.Label
        ParcelsincludedListBox       matlab.ui.control.ListBox
        ADDSelectedButton            matlab.ui.control.Button
        REMOVEselectedButton         matlab.ui.control.Button
        BrainpainterPanel            matlab.ui.container.Panel
        SliderLabel                  matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        SaveROIButton                matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        repoPath = fileparts(mfilename('fullpath'));
        pathnameHeadModel;
        pathnameROI;
        gmSurfaceMesh;
        ROI = struct('gmNodeList',[],'scalpPosList',[]);
    end
    
    
    methods (Access = private)
        
        function startupFcn(app, pathnameROI, pathnameHeadModel)
            if exist(pathnameROI,'var')
                if ~isempty(pathnameROI)
                    app.pathnameROI = pathnameROI;
                end
            end
            if exist(pathnameHeadModel,'var')
                if ~isempty(pathnameHeadModel)
                    app.pathnameHeadModel = pathnameHeadModel;
                end
            end
        end
        
%         function results = updateAll(app)
%             updateVariables(app)
%             updateFigures(app)
%         end
%         
%         function results = updateVariables(app)
%             app.HeadModelPathText = app.pathnameHeadModel;
%             app.ROIPathText = app.pathnameROI;
%             
%         end
        
        function results = updateFigures(app)
            
            %Update ROI plot
            cla(app.ROIAxes);
            if ~isempty(app.ROI) && ~isempty(app.gmSurfaceMesh)
                plotROI(app.gmSurfaceMesh,app.ROI,'hAxes',app.ROIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectHeadModelButton
        function SelectHeadModelButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile([app.repoPath '/*.mshs'],'Select .mshs file');
            fullpath = [pathname filename];
            [pathname,filename,ext] = fileparts(fullpath);
            pathnameHeadModeltmp = [pathname '/' filename];
            mshstmp = load([pathnameHeadModeltmp ext],'-mat');
            
            %Check if there is a folder pwd/HeadModels/filename. If not,
            %create and save mshs there
            if ~exist([app.repoPath '/HeadModels/' filename],'dir')
               mkdir([app.repoPath '/HeadModels/' filename]);
               save(pathnameHeadModeltmp,'-struct','mshstmp');
               app.pathnameHeadModel = [app.repoPath '/HeadModels/' filename];
           
            %If headmodel exists with this name, but specified path is different. Warn and overwrite
            elseif ~strcmpi([app.repoPath '/HeadModels/' filename '/' filename],pathnameHeadModeltmp)  
                selection = uiconfirm(app.UIFigure,'A head model with this name already exists in ArrayDesigner','Head Model','Options',{'Use Existing','Overwrite Existing'},'DefaultOption',1,...
                        'Icon','warning');
                    
                    if strcmpi(selection,'Use Existing')
                        app.pathnameHeadModel = [app.repoPath '/HeadModels/' filename];

                    elseif strcmpi(selection,'Overwrite Existing')
                        save([app.repoPath '/HeadModels/' filename ext],'-struct','mshstmp');
                        app.pathnameHeadModel = [app.repoPath '/HeadModels/' filename];
                    end
            else %Headmodel is existing one selected from HeadModels folder
                %Specify pathnameHeadModel as that selected
                app.pathnameHeadModel = [app.repoPath '/HeadModels/' filename];
            end
                         
            %Update text field
            app.HeadModelPathText.Value = [filename ext];
            
            %Load gmSurfaceMesh
            app.gmSurfaceMesh = mshstmp.gmSurfaceMesh;
            
            %Clear ROI text field
            app.CurrentROIText.Value = '';
            app.pathnameROI = '';
            app.ROI = struct('gmNodeList',[],'scalpPosList',[]);
            
            %Update axes
            updateFigures(app);
            
            %Update parcellation dropdown
            if isfield(app.gmSurfaceMesh,'parcellation')
                nParcellations = size(app.gmSurfaceMesh.parcellation);
                for i = 1:nParcellations
                    app.SelectParcellationDropDown.Items{i} = app.gmSurfaceMesh.parcellation(i).name;
                end
                app.SelectParcellationDropDown.Value = app.SelectParcellationDropDown.Items{1};
                SelectParcellationDropDownValueChanged(app);
            end
            
           
        end

        % Button pushed function: SelectExistingROIButton
        function SelectExistingROIButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile([app.repoPath '/*.txt'],'Select ROI text file');
            fullpath = [pathname filename];
            [pathname,filename,ext] = fileparts(fullpath);
            app.pathnameROI = [pathname '/' filename ext];
            app.ROI = loadROI(app.pathnameROI);
            app.CurrentROIText.Value = [filename ext];
            
            %Determine headmodel from ROI location (if possible)
            ind = strfind(app.pathnameROI,'/ROIs/');
            pathnameHeadModeltmp = app.pathnameROI(1:ind-1);
            ind2 = strfind(pathnameHeadModeltmp,'/');
            ind2 = ind2(end)+1;
            mshsname = [pathnameHeadModeltmp(ind2:end) '.mshs'];
            if exist([pathnameHeadModeltmp '/' mshsname],'file')
                app.pathnameHeadModel = pathnameHeadModeltmp;
                mshstmp = load([pathnameHeadModeltmp '/' mshsname],'-mat');
            end
            
            %Update text field
            app.HeadModelPathText.Value = [filename ext];
            
            %Load gmSurfaceMesh
            app.gmSurfaceMesh = mshstmp.gmSurfaceMesh;
            
            %Update axes
            updateFigures(app);
            
            %Update parcellation dropdown
            if isfield(app.gmSurfaceMesh,'parcellation')
                nParcellations = size(app.gmSurfaceMesh.parcellation);
                for i = 1:nParcellations
                    app.SelectParcellationDropDown.Items{i} = app.gmSurfaceMesh.parcellation(i).name;
                end
                app.SelectParcellationDropDown.Value = app.SelectParcellationDropDown.Items{1};
                SelectParcellationDropDownValueChanged(app);
            end
        end

        % Value changed function: SelectParcellationDropDown
        function SelectParcellationDropDownValueChanged(app, event)
            value = app.SelectParcellationDropDown.Value;
            %Update parcelsavailable list
            ind = find(strcmp(app.SelectParcellationDropDown.Items,app.SelectParcellationDropDown.Value));
            app.ParcelsallListBox.Items = app.gmSurfaceMesh.parcellations(ind).labels;
        end

        % Button pushed function: ADDSelectedButton
        function ADDSelectedButtonPushed(app, event)
            currList = app.ParcelsincludedListBox.Items;
            added = app.ParcelsallListBox.Value;
            toAdd = {};
            count = 1;
            for i = 1:length(added)
                if ~any(ismember(app.ParcelsincludedListBox.Items,added{i}))
                    toAdd{count} = added{i};
                    count = count + 1;
                end
            end
            app.ParcelsincludedListBox.Items = [currList toAdd];
            
            %UPDATE ROI
            
            
        end

        % Button pushed function: REMOVEselectedButton
        function REMOVEselectedButtonPushed(app, event)
            currList = app.ParcelsincludedListBox.Items;
            toRem = app.ParcelsincludedListBox.Value;
            for i = 1:length(toRem)
                currList = currList(~ismember(currList,toRem{i}));
            end
            app.ParcelsincludedListBox.Items = currList;
            
            %UPDATE ROI
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ROIAxes = uiaxes(app.UIFigure);
            title(app.ROIAxes, '')
            xlabel(app.ROIAxes, 'X (mm)')
            ylabel(app.ROIAxes, 'Y (mm)')
            app.Panel = uipanel(app.UIFigure);
            app.SelectHeadModelButton = uibutton(app.Panel, 'push');
            app.SelectHeadModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectHeadModelButtonPushed, true);
            app.SelectHeadModelButton.Text = 'Select Head Model';
            app.HeadModelPathText = uieditfield(app.Panel, 'text');
            app.HeadModelPathText.Editable = 'off';
            app.HeadModelPathText.Tooltip = {'Select the head model (.mshs file) in which you want to specify your ROI'};
            app.SelectExistingROIButton = uibutton(app.Panel, 'push');
            app.SelectExistingROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExistingROIButtonPushed, true);
            app.SelectExistingROIButton.Text = 'Select Existing ROI';
            app.CurrentROIText = uieditfield(app.Panel, 'text');
            app.CurrentROIText.Editable = 'off';
            app.CurrentROIText.Tooltip = {'Select an exisiting ROI file to edit (.txt)'};
            app.SelectParcellationDropDownLabel = uilabel(app.Panel);
            app.SelectParcellationDropDownLabel.Text = 'Select Parcellation';
            app.SelectParcellationDropDown = uidropdown(app.Panel);
            app.SelectParcellationDropDown.Items = {};
            app.SelectParcellationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectParcellationDropDownValueChanged, true);
            app.SelectParcellationDropDown.Tooltip = {'Choose a parcellation from those present within the selected head model .gmSurfaceMesh'};
            app.SelectParcellationDropDown.Value = {};
            app.ROIBuilderLabel = uilabel(app.UIFigure);
            app.ROIBuilderLabel.Text = 'ROI Builder';
            app.ParcelselectorPanel = uipanel(app.UIFigure);
            app.ParcelselectorPanel.Title = 'Parcel selector';
            app.ADDSelectedButton = uibutton(app.ParcelselectorPanel, 'push');
            app.ADDSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ADDSelectedButtonPushed, true);
            app.ADDSelectedButton.Tooltip = {'Add the selected parcels to your ROI'};
            app.ADDSelectedButton.Text = 'ADD Selected >';
            app.REMOVEselectedButton = uibutton(app.ParcelselectorPanel, 'push');
            app.REMOVEselectedButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEselectedButtonPushed, true);
            app.REMOVEselectedButton.Tooltip = {'Remove the selected parcels from your ROI'};
            app.REMOVEselectedButton.Text = '< REMOVE selected';
            app.ParcelsallListBoxLabel = uilabel(app.ParcelselectorPanel);
            app.ParcelsallListBoxLabel.Text = 'Parcels (all) ';
            app.ParcelsallListBox = uilistbox(app.ParcelselectorPanel);
            app.ParcelsallListBox.Items = {};
            app.ParcelsallListBox.Multiselect = 'on';
            app.ParcelsallListBox.Tooltip = {'Listed here are all the parcels in the seelcted parcellation'};
            app.ParcelsallListBox.Value = {};
            app.ParcelsincludedListBoxLabel = uilabel(app.ParcelselectorPanel);
            app.ParcelsincludedListBoxLabel.Text = 'Parcels (included)';
            app.ParcelsincludedListBox = uilistbox(app.ParcelselectorPanel);
            app.ParcelsincludedListBox.Items = {};
            app.ParcelsincludedListBox.Multiselect = 'on';
            app.ParcelsincludedListBox.Tooltip = {'Listed here are all the parcels you have selected as part fo your ROI'};
            app.ParcelsincludedListBox.Value = {};
            app.BrainpainterPanel = uipanel(app.UIFigure);
            app.BrainpainterPanel.Title = 'Brain painter';
            app.SliderLabel = uilabel(app.BrainpainterPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.BrainpainterPanel);
            app.SaveROIButton = uibutton(app.UIFigure, 'push');
            app.SaveROIButton.Text = 'Save ROI';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIBuilder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end